classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PositionsTab                 matlab.ui.container.Tab
        position2                    matlab.ui.control.UIAxes
        position1                    matlab.ui.control.UIAxes
        VelocitiesTab                matlab.ui.container.Tab
        velocity1                    matlab.ui.control.UIAxes
        velocity2                    matlab.ui.control.UIAxes
        TabGroup2                    matlab.ui.container.TabGroup
        PositionsreadingTab          matlab.ui.container.Tab
        ReadButton                   matlab.ui.control.Button
        Joint1angleLabel             matlab.ui.control.Label
        Joint1rad                    matlab.ui.control.Label
        Joint2angleLabel             matlab.ui.control.Label
        Joint2rad                    matlab.ui.control.Label
        radsLabel                    matlab.ui.control.Label
        radsLabel_2                  matlab.ui.control.Label
        Joint1degree                 matlab.ui.control.Label
        degreesLabel                 matlab.ui.control.Label
        Joint2degree                 matlab.ui.control.Label
        degreesLabel_2               matlab.ui.control.Label
        ReadpositionsonceLabel       matlab.ui.control.Label
        ReadforawhileLabel           matlab.ui.control.Label
        PositionsangleswillbeplottedinthepanelbelowLabel  matlab.ui.control.Label
        ReadandplotSwitchLabel       matlab.ui.control.Label
        ReadandplotSwitch            matlab.ui.control.ToggleSwitch
        ExportasCSVfileButton        matlab.ui.control.Button
        ReadingtimeEditFieldLabel    matlab.ui.control.Label
        ReadingtimeEditField         matlab.ui.control.NumericEditField
        sLabel                       matlab.ui.control.Label
        ConfirmButton                matlab.ui.control.StateButton
        PositionreadingLabel         matlab.ui.control.Label
        RotationcontrolTab           matlab.ui.container.Tab
        RotationspeedcontrolLabel    matlab.ui.control.Label
        Motor1speedLabel             matlab.ui.control.Label
        Motor1speedSlider            matlab.ui.control.Slider
        Motor1speedEditField         matlab.ui.control.NumericEditField
        RotationswitchSwitchLabel    matlab.ui.control.Label
        RotationswitchSwitch         matlab.ui.control.ToggleSwitch
        BrakeswitchSwitchLabel       matlab.ui.control.Label
        Brake1switchSwitch           matlab.ui.control.Switch
        BrakeswitchSwitch_2Label     matlab.ui.control.Label
        Brake2switchSwitch           matlab.ui.control.Switch
        Motor2speedEditField         matlab.ui.control.NumericEditField
        Motor2speedSliderLabel       matlab.ui.control.Label
        Motor2speedSlider            matlab.ui.control.Slider
        PIDpositioncontrolTab        matlab.ui.container.Tab
        Kp1EditFieldLabel            matlab.ui.control.Label
        Kp1EditField                 matlab.ui.control.NumericEditField
        Ki1EditFieldLabel            matlab.ui.control.Label
        Ki1EditField                 matlab.ui.control.NumericEditField
        Kd1EditFieldLabel            matlab.ui.control.Label
        Kd1EditField                 matlab.ui.control.NumericEditField
        Kp2EditFieldLabel            matlab.ui.control.Label
        Kp2EditField                 matlab.ui.control.NumericEditField
        Ki2EditFieldLabel            matlab.ui.control.Label
        Ki2EditField                 matlab.ui.control.NumericEditField
        Kd2EditFieldLabel            matlab.ui.control.Label
        Kd2EditField                 matlab.ui.control.NumericEditField
        BrakeswitchSwitchLabel_2     matlab.ui.control.Label
        Brake1switchSwitch_2         matlab.ui.control.Switch
        BrakeswitchSwitch_2Label_2   matlab.ui.control.Label
        Brake2switchSwitch_2         matlab.ui.control.Switch
        RunningswitchSwitchLabel     matlab.ui.control.Label
        RunningPIDswitchSwitch       matlab.ui.control.ToggleSwitch
        Joint1EditFieldLabel         matlab.ui.control.Label
        Joint1EditField              matlab.ui.control.NumericEditField
        Joint2EditFieldLabel         matlab.ui.control.Label
        Joint2EditField              matlab.ui.control.NumericEditField
        ImportedtrajectoryEditFieldLabel  matlab.ui.control.Label
        ImportedtrajectoryEditField  matlab.ui.control.EditField
        TrajectoryDropDownLabel      matlab.ui.control.Label
        TrajectoryDropDown           matlab.ui.control.DropDown
        SimulateButton               matlab.ui.control.Button
        JointinitialpositionsLabel   matlab.ui.control.Label
        DesiredtrajectoryLabel       matlab.ui.control.Label
        PIDConfirmButton             matlab.ui.control.StateButton
        ExportButton                 matlab.ui.control.Button
        ControlgainsLabel            matlab.ui.control.Label
        SettingsTab                  matlab.ui.container.Tab
        RobotpositionSwitchLabel     matlab.ui.control.Label
        RobotpositionSwitch          matlab.ui.control.Switch
        DecoggingSwitchLabel         matlab.ui.control.Label
        DecoggingSwitch              matlab.ui.control.Switch
        ConnectionLampLabel          matlab.ui.control.Label
        ConnectionLamp               matlab.ui.control.Lamp
        CommunicationLampLabel       matlab.ui.control.Label
        CommunicationLamp            matlab.ui.control.Lamp
        RunningLampLabel             matlab.ui.control.Label
        RunningLamp                  matlab.ui.control.Lamp
        CommandOptionsLabel          matlab.ui.control.Label
        COMportEditFieldLabel        matlab.ui.control.Label
        COMportEditField             matlab.ui.control.EditField
        ConnectButton                matlab.ui.control.StateButton
        GUIforR2RRobotLabel          matlab.ui.control.Label
        SimulationPlot               matlab.ui.control.UIAxes
        CalibrationButton            matlab.ui.control.Button
        PositionplotsLabel           matlab.ui.control.Label
        ErrorLampLabel               matlab.ui.control.Label
        ErrorLamp                    matlab.ui.control.Lamp
        SavingLampLabel              matlab.ui.control.Label
        SavingLamp                   matlab.ui.control.Lamp
    end

    properties (Access = private)
        userserial % Description
        fig1
        fig2
        fig3
        fig4
    end

    properties (Access = public)
        v % Description
        traj
        t
        dtraj
    end

    methods (Access = private)   
        function velocityProcess(app)
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                if strcmp(app.Brake1switchSwitch.Value, 'Off') && strcmp(app.Brake2switchSwitch.Value, 'Off')
                    velocitySend(app.v, 3, app.userserial);
                elseif strcmp(app.Brake1switchSwitch.Value, 'Off') && strcmp(app.Brake2switchSwitch.Value, 'On')
                    velocitySend(app.v, 1, app.userserial);
                elseif strcmp(app.Brake1switchSwitch.Value, 'On') && strcmp(app.Brake2switchSwitch.Value, 'Off')
                    velocitySend(app.v, 2, app.userserial);
                end
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.position1, 'color', 'white');
            set(app.position2, 'color', 'white');
            set(app.SimulationPlot, 'color', 'white');
        end

        % Value changing function: Motor1speedSlider
        function Motor1speedSliderValueChanging(app, event)
            value = event.Value;
            app.Motor1speedEditField.Value = value;
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                app.v(1) = value;
                velocityProcess(app);
            end
        end

        % Value changed function: Motor1speedEditField
        function Motor1speedEditFieldValueChanged(app, event)
            value = app.Motor1speedEditField.Value;
            if value > 100
                value = 100;
                app.Motor1speedEditField.Value = 100;
            elseif value < -100
                value = -100;
                app.Motor1speedEditField.Value = -100;
            end
            app.Motor1speedSlider.Value = value;
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                app.v(1) = value;
                velocityProcess(app);
            end           
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.COMportEditField.Value;
            if app.ConnectButton.Value == 1
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                if ismember(value, seriallist)
                    app.userserial = serialConfig(value);
                    fopen(app.userserial);
                    app.RotationswitchSwitch.Enable = 'on';
                    app.CalibrationButton.Enable = 'on';
                    app.ReadButton.Enable = 'on';
                    app.ConfirmButton.Enable = 'on'; 
                    app.PIDConfirmButton.Enable = 'on';           
                else
                    app.ConnectButton.Value = 0;
                end
            else
                fclose(app.userserial);
                % Turn off all the switches.
                app.RotationswitchSwitch.Value = 'Off';
                
                % Disable most of the buttons.
                app.RotationswitchSwitch.Enable = 'off';
                app.CalibrationButton.Enable = 'off';
                app.ReadButton.Enable = 'off';
                app.ConfirmButton.Enable = 'off';
                app.PIDConfirmButton.Enable = 'off'; 
            end
        end

        % Value changing function: Motor2speedSlider
        function Motor2speedSliderValueChanging(app, event)
            value = event.Value;
            app.Motor2speedEditField.Value = value;
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                app.v(2) = value;
                velocityProcess(app);
            end        
        end

        % Value changed function: Motor2speedEditField
        function Motor2speedEditFieldValueChanged(app, event)
            value = app.Motor2speedEditField.Value;
            if value > 100
                value = 100;
                app.Motor2speedEditField.Value = 100;
            elseif value < -100
                value = -100;
                app.Motor2speedEditField.Value = -100;
            end
            app.Motor2speedSlider.Value = value;
            if strcmp(app.RotationswitchSwitch.Value, 'On')
                app.v(2) = value;
                velocityProcess(app);
            end
        end

        % Value changed function: RotationswitchSwitch
        function RotationswitchSwitchValueChanged(app, event)
            value = app.RotationswitchSwitch.Value;
            if strcmp(value, 'On')
                app.v = [app.Motor1speedEditField.Value, app.Motor2speedEditField.Value];
            else
                app.v = [0, 0]; 
            end
            velocityProcess(app);            
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            window = helpdlg('Please move and hold the joints at their zero positions until the calibration finishes.');
            waitfor(window);
            setZero(app.userserial);
            msgbox('Calibration finished!', 'Success');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Do not know if this is necessary.
            if ~isempty(app.userserial)
                finishup = onCleanup(@()fclose(app.userserial)); %closes serial on termination!
            end
            delete(app);           
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            p = positionRead(3, app.userserial);
            app.Joint1rad.Text = num2str(p(1));
            app.Joint1degree.Text = num2str(p(2));
            app.Joint2rad.Text = num2str(p(3));
            app.Joint2degree.Text = num2str(p(4));
        end

        % Value changed function: ReadandplotSwitch
        function ReadandplotSwitchValueChanged(app, event)
            if strcmp(app.ReadandplotSwitch.Value, 'On')
                app.ReadandplotSwitch.Enable = 'off';
                delete(app.fig1);
                delete(app.fig2);
                app.fig1 = animatedline(app.position1);
                app.fig2 = animatedline(app.position2);  
                hold(app.position1,'on');
                hold(app.position2,'on');
                app.traj = zeros(app.t, 2);
                temp = [0, 0];
                fwrite(app.userserial, 12);
                for i = 1: app.t / 10
                    temp(1) = readFloat(app.userserial);
                    temp(2) = readFloat(app.userserial);
                    app.traj(i, :) = temp;
                    if rem(i, 5) == 0
                        addpoints(app.fig1, i / 100, temp(1));
                        addpoints(app.fig2, i / 100, temp(2));
                        drawnow limitrate;
                    end
                end 
                app.ReadandplotSwitch.Enable = 'on';
                app.ExportasCSVfileButton.Enable = 'on';
                app.ReadandplotSwitch.Value = 'Off'; 
            end
        end

        % Value changed function: ConfirmButton
        function ConfirmButtonValueChanged(app, event)
            app.t = app.ReadingtimeEditField.Value * 1000;
            if app.ConfirmButton.Value && app.t > 0
                timeSend(floor(app.t), app.userserial);
                app.ReadandplotSwitch.Enable = 'on';           
                delete(app.fig1);
                delete(app.fig2);
                axis(app.position1, [0, app.t / 1000, -2 * pi, 2 * pi]);
                axis(app.position2, [0, app.t / 1000, -2 * pi, 2 * pi]);
            else
                app.ConfirmButton.Value = 0;
            end          
        end

        % Value changed function: ReadingtimeEditField
        function ReadingtimeEditFieldValueChanged(app, event)
            value = app.ReadingtimeEditField.Value;
            app.ConfirmButton.Value = 0;
            if value > 0
                app.ConfirmButton.Value = 0;
            elseif value > 10
                app.ConfirmButton.Value = 10;
            elseif rem(value * 1000, 1) ~= 0
                app.ConfirmButton.Value = floor(rem(value * 1000, 1)) / 1000;
            end
        end

        % Button pushed function: ExportasCSVfileButton
        function ExportasCSVfileButtonPushed(app, event)
            trajectoryExport(app.traj);
            msgbox('Trajectories saved', 'Success');
            app.ExportasCSVfileButton.Enable = 'off';
        end

        % Value changed function: PIDConfirmButton
        function PIDConfirmButtonValueChanged(app, event)
            if app.PIDConfirmButton.Value
                if strcmp(app.TrajectoryDropDown, 'Imported') 
                    if ~exist(app.ImportedtrajectoryEditedField.Value, 'file')
                        app.PIDConfirmButton.Value = 0;
                        errordlg('File not found', 'Imported file error');
                    end
                    app.dtraj = csvread(app.ImportedtrajectoryEditedField.Value); 
                elseif strcmp(app.TrajectoryDropDown, 'Costumed')
                    app.dtraj = trajectoryCostumed();
                else
                    app.dtraj = trajectoryDefault();                   
                end
                app.t = size(app.dtraj, 1);
                if app.t <= 0
                    app.PIDConfirmButton.Value = 0;
                    errordlg('Trajectory not available', 'Trajectory error');
                end               
                initialPositionSend([app.Joint1EditField.Value, app.Joint2EditField.Value], app.userserial);            
                PIDSend([app.Kp1EditField.Value, app.Ki1EditField.Value, app.Kd1EditField.Value, app.Kp2EditField.Value, app.Ki2EditField.Value, app.Kd2EditField.Value], 3, app.userserial);
                trajectorySend(app.dtraj, 3, app.userserial);               
                app.RunningPIDswitchSwitch.Enable = 'on';  
                app.SimulateButton.Enable = 'on'; 
                delete(app.fig1);
                delete(app.fig2);
                delete(app.fig3);
                delete(app.fig4);
                hold(app.position1,'on');
                hold(app.position2,'on');
                time = 50: 50: app.t;
                app.fig3 = plot(app.position1, time / 1000, app.dtraj(time, 1)', 'r');
                app.fig4 = plot(app.position2, time / 1000, app.dtraj(time, 2)', 'r');
            else
                app.PIDConfirmButton.Value = 0;
            end
        end

        % Value changed function: TrajectoryDropDown
        function TrajectoryDropDownValueChanged(app, event)
            app.PIDConfirmButton.Value = 0;
            if strcmp(app.TrajectoryDropDown.Value, 'Imported')
                app.ImportedtrajectoryEditField.Enable = 'on';
            else
                app.ImportedtrajectoryEditField.Enable = 'off';
            end
        end

        % Value changed function: Joint1EditField
        function Joint1EditFieldValueChanged(app, event)
            app.PIDConfirmButton.Value = 0;
        end

        % Value changed function: Joint2EditField
        function Joint2EditFieldValueChanged(app, event)
            app.PIDConfirmButton.Value = 0;     
        end

        % Value changing function: ImportedtrajectoryEditField
        function ImportedtrajectoryEditFieldValueChanging(app, event)
            app.PIDConfirmButton.Value = 0; 
        end

        % Value changed function: Kp1EditField
        function Kp1EditFieldValueChanged(app, event)
            app.PIDConfirmButton.Value = 0;            
        end

        % Value changed function: Ki1EditField
        function Ki1EditFieldValueChanged(app, event)
            app.PIDConfirmButton.Value = 0; 
        end

        % Value changed function: Kd1EditField
        function Kd1EditFieldValueChanged(app, event)
            app.PIDConfirmButton.Value = 0; 
        end

        % Value changed function: Kp2EditField
        function Kp2EditFieldValueChanged(app, event)
            app.PIDConfirmButton.Value = 0; 
        end

        % Value changed function: Ki2EditField
        function Ki2EditFieldValueChanged(app, event)
            app.PIDConfirmButton.Value = 0; 
        end

        % Value changed function: Kd2EditField
        function Kd2EditFieldValueChanged(app, event)
            app.PIDConfirmButton.Value = 0; 
        end

        % Value changed function: RunningPIDswitchSwitch
        function RunningPIDswitchSwitchValueChanged(app, event)
            if strcmp(app.RunningPIDswitchSwitch.Value, 'On')
                app.RunningPIDswitchSwitch.Enable = 'off';
                delete(app.fig1);
                delete(app.fig2);
                app.fig1 = animatedline(app.position1);
                app.fig2 = animatedline(app.position2);  
                hold(app.position1,'on');
                hold(app.position2,'on');
                app.traj = zeros(app.t, 2);
                temp = [0, 0];
                fwrite(app.userserial, 22);
                for i = 1: app.t / 10
                    temp(1) = readFloat(app.userserial);
                    temp(2) = readFloat(app.userserial);
                    app.traj(i, :) = temp;
                    if rem(i, 5) == 0
                        addpoints(app.fig1, i / 100, temp(1));
                        addpoints(app.fig2, i / 100, temp(2));
                        drawnow limitrate;
                    end
                end 
                legend(app.position1, 'Desired', 'Actual');
                legend(app.position2, 'Desired', 'Actual');
                app.RunningPIDswitchSwitch.Enable = 'on';
                app.ExportasPIDCSVfileButton.Enable = 'on';
                app.RunningPIDswitchSwitch.Value = 'Off'; 
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            trajectoryExport([app.traj, app.dtraj]);
            msgbox('Trajectories saved', 'Success');
            app.ExportasCSVfileButton.Enable = 'off';
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            %***Choose the figure.
            hold(app.SimulationPlot, 'on');
            axis(app.SimulationPlot, 'equal');
            axis(app.SimulationPlot, [-2.2, 2.2, -2.2, 2.2]);           
            %***Plot background.
            plot(app.SimulationPlot, 0, 0, 'Marker', '.', 'MarkerSize', 30, 'MarkerEdgeColor', 'k');
            line(app.SimulationPlot, [0, 1], [0, 0], 'LineStyle', '--', 'Color', 'k', 'LineWidth', 3);
            %***Plot the moving parts.
            dt = 0.05;
            P = [];
            format shortg
            temp = clock();
            tcurrent = temp(6);
            min = temp(5);
            i = 0;
            duration = size(app.dtraj, 1);
            % The simulation will delay dt second to begin.
            while i < duration
                temp = clock();
                if temp(5) > min
                    temp(6) = temp(6) + 60;
                end
                if temp(6) > tcurrent + dt
                    tcurrent = temp(6);
                    i = i + 50;
                    if i > duration
                        i = duration;
                    end
                    delete(P);
                    P = [line(app.SimulationPlot, [0, cos(app.dtraj(i, 1))], [0, sin(app.dtraj(i, 1))], 'Color', 'k', 'LineWidth', 3);
                         line(app.SimulationPlot, [cos(app.dtraj(i, 1)), cos(app.dtraj(i, 1)) + cos(app.dtraj(i, 1) + app.dtraj(i, 2))], [sin(app.dtraj(i, 1)), sin(app.dtraj(i, 1)) + sin(app.dtraj(i, 1) + app.dtraj(i, 2))], 'Color', 'k', 'LineWidth', 3);
                         line(app.SimulationPlot, [cos(app.dtraj(i, 1)), 2 * cos(app.dtraj(i, 1))], [sin(app.dtraj(i, 1)), 2 * sin(app.dtraj(i, 1))], 'LineStyle', '--', 'Color', 'k', 'LineWidth', 3);
                         plot(app.SimulationPlot, cos(app.dtraj(i, 1)), sin(app.dtraj(i, 1)), 'Marker', '.', 'MarkerSize', 30, 'MarkerEdgeColor', 'k')]; 
                    drawnow limitrate;
                end
            end
        end

        % Value changed function: DecoggingSwitch
        function DecoggingSwitchValueChanged(app, event)
            if strcmp(app.DecoggingSwitch.Value, 'Off')
                fwrite(serial, 29);
            else
                fwrite(serial, 28);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PositionsTab = uitab(app.TabGroup);
            app.PositionsTab.Title = 'Positions';
            app.position2 = uiaxes(app.PositionsTab);
            title(app.position2, 'Title')
            xlabel(app.position2, 't')
            ylabel(app.position2, 'Theta 2')
            app.position2.XLim = [0 1];
            app.position2.YLim = [0 1];
            app.position2.ZLim = [0 1];
            app.position2.CLim = [0 1];
            app.position2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.position2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.position2.ZTick = [0 0.5 1];
            app.position1 = uiaxes(app.PositionsTab);
            title(app.position1, 'Title')
            xlabel(app.position1, 't')
            ylabel(app.position1, 'Theta 1')
            app.position1.XLim = [0 1];
            app.position1.YLim = [0 1];
            app.position1.ZLim = [0 1];
            app.position1.CLim = [0 1];
            app.position1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.position1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.position1.ZTick = [0 0.5 1];
            app.VelocitiesTab = uitab(app.TabGroup);
            app.VelocitiesTab.Title = 'Velocities';
            app.velocity1 = uiaxes(app.VelocitiesTab);
            title(app.velocity1, 'Joint 1')
            xlabel(app.velocity1, 'Time (s)')
            ylabel(app.velocity1, 'Theta 1 (rads)')
            app.velocity1.XLim = [0 1];
            app.velocity1.YLim = [0 1];
            app.velocity1.ZLim = [0 1];
            app.velocity1.CLim = [0 1];
            app.velocity1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.velocity1.YTick = [0 0.5 1];
            app.velocity1.ZTick = [0 0.5 1];
            app.velocity2 = uiaxes(app.VelocitiesTab);
            title(app.velocity2, 'Joint 2')
            xlabel(app.velocity2, 'Time (s)')
            ylabel(app.velocity2, 'Theta 2 (rads)')
            app.velocity2.XLim = [0 1];
            app.velocity2.YLim = [0 1];
            app.velocity2.ZLim = [0 1];
            app.velocity2.CLim = [0 1];
            app.velocity2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.velocity2.YTick = [0 0.5 1];
            app.velocity2.ZTick = [0 0.5 1];
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.PositionsreadingTab = uitab(app.TabGroup2);
            app.PositionsreadingTab.Title = 'Positions reading';
            app.ReadButton = uibutton(app.PositionsreadingTab, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Enable = 'off';
            app.ReadButton.Text = 'Read';
            app.Joint1angleLabel = uilabel(app.PositionsreadingTab);
            app.Joint1angleLabel.Text = 'Joint 1 angle:';
            app.Joint1rad = uilabel(app.PositionsreadingTab);
            app.Joint1rad.Text = '0';
            app.Joint2angleLabel = uilabel(app.PositionsreadingTab);
            app.Joint2angleLabel.Text = 'Joint 2 angle:';
            app.Joint2rad = uilabel(app.PositionsreadingTab);
            app.Joint2rad.Text = '0';
            app.radsLabel = uilabel(app.PositionsreadingTab);
            app.radsLabel.Text = 'rad(s)';
            app.radsLabel_2 = uilabel(app.PositionsreadingTab);
            app.radsLabel_2.Text = 'rad(s)';
            app.Joint1degree = uilabel(app.PositionsreadingTab);
            app.Joint1degree.Text = '0';
            app.degreesLabel = uilabel(app.PositionsreadingTab);
            app.degreesLabel.Text = 'degree(s)';
            app.Joint2degree = uilabel(app.PositionsreadingTab);
            app.Joint2degree.Text = '0';
            app.degreesLabel_2 = uilabel(app.PositionsreadingTab);
            app.degreesLabel_2.Text = 'degree(s)';
            app.ReadpositionsonceLabel = uilabel(app.PositionsreadingTab);
            app.ReadpositionsonceLabel.Text = 'Read positions once';
            app.ReadforawhileLabel = uilabel(app.PositionsreadingTab);
            app.ReadforawhileLabel.Text = 'Read for a while';
            app.PositionsangleswillbeplottedinthepanelbelowLabel = uilabel(app.PositionsreadingTab);
            app.PositionsangleswillbeplottedinthepanelbelowLabel.Text = 'Positions/angles will be plotted in the panel below.';
            app.ReadandplotSwitchLabel = uilabel(app.PositionsreadingTab);
            app.ReadandplotSwitchLabel.Text = 'Read and plot';
            app.ReadandplotSwitch = uiswitch(app.PositionsreadingTab, 'toggle');
            app.ReadandplotSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadandplotSwitchValueChanged, true);
            app.ReadandplotSwitch.Enable = 'off';
            app.ExportasCSVfileButton = uibutton(app.PositionsreadingTab, 'push');
            app.ExportasCSVfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasCSVfileButtonPushed, true);
            app.ExportasCSVfileButton.Enable = 'off';
            app.ExportasCSVfileButton.Text = 'Export as CSV file';
            app.ReadingtimeEditFieldLabel = uilabel(app.PositionsreadingTab);
            app.ReadingtimeEditFieldLabel.Text = 'Reading time';
            app.ReadingtimeEditField = uieditfield(app.PositionsreadingTab, 'numeric');
            app.ReadingtimeEditField.ValueChangedFcn = createCallbackFcn(app, @ReadingtimeEditFieldValueChanged, true);
            app.sLabel = uilabel(app.PositionsreadingTab);
            app.sLabel.Text = 's';
            app.ConfirmButton = uibutton(app.PositionsreadingTab, 'state');
            app.ConfirmButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonValueChanged, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm';
            app.PositionreadingLabel = uilabel(app.PositionsreadingTab);
            app.PositionreadingLabel.Text = 'Position reading';
            app.RotationcontrolTab = uitab(app.TabGroup2);
            app.RotationcontrolTab.Title = 'Rotation control';
            app.RotationspeedcontrolLabel = uilabel(app.RotationcontrolTab);
            app.RotationspeedcontrolLabel.Text = 'Rotation speed control';
            app.Motor1speedLabel = uilabel(app.RotationcontrolTab);
            app.Motor1speedLabel.Text = 'Motor 1 speed';
            app.Motor1speedSlider = uislider(app.RotationcontrolTab);
            app.Motor1speedSlider.Limits = [-100 100];
            app.Motor1speedSlider.ValueChangingFcn = createCallbackFcn(app, @Motor1speedSliderValueChanging, true);
            app.Motor1speedEditField = uieditfield(app.RotationcontrolTab, 'numeric');
            app.Motor1speedEditField.ValueChangedFcn = createCallbackFcn(app, @Motor1speedEditFieldValueChanged, true);
            app.RotationswitchSwitchLabel = uilabel(app.RotationcontrolTab);
            app.RotationswitchSwitchLabel.Text = {'Rotation'; 'switch'};
            app.RotationswitchSwitch = uiswitch(app.RotationcontrolTab, 'toggle');
            app.RotationswitchSwitch.ValueChangedFcn = createCallbackFcn(app, @RotationswitchSwitchValueChanged, true);
            app.RotationswitchSwitch.Enable = 'off';
            app.BrakeswitchSwitchLabel = uilabel(app.RotationcontrolTab);
            app.BrakeswitchSwitchLabel.Text = 'Brake switch';
            app.Brake1switchSwitch = uiswitch(app.RotationcontrolTab, 'slider');
            app.BrakeswitchSwitch_2Label = uilabel(app.RotationcontrolTab);
            app.BrakeswitchSwitch_2Label.Text = 'Brake switch';
            app.Brake2switchSwitch = uiswitch(app.RotationcontrolTab, 'slider');
            app.Motor2speedEditField = uieditfield(app.RotationcontrolTab, 'numeric');
            app.Motor2speedEditField.ValueChangedFcn = createCallbackFcn(app, @Motor2speedEditFieldValueChanged, true);
            app.Motor2speedSliderLabel = uilabel(app.RotationcontrolTab);
            app.Motor2speedSliderLabel.Text = 'Motor 2 speed';
            app.Motor2speedSlider = uislider(app.RotationcontrolTab);
            app.Motor2speedSlider.Limits = [-100 100];
            app.Motor2speedSlider.ValueChangingFcn = createCallbackFcn(app, @Motor2speedSliderValueChanging, true);
            app.PIDpositioncontrolTab = uitab(app.TabGroup2);
            app.PIDpositioncontrolTab.Title = 'PID position control';
            app.Kp1EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Kp1EditFieldLabel.Text = 'Kp1';
            app.Kp1EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Kp1EditField.ValueChangedFcn = createCallbackFcn(app, @Kp1EditFieldValueChanged, true);
            app.Ki1EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Ki1EditFieldLabel.Text = 'Ki1';
            app.Ki1EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Ki1EditField.ValueChangedFcn = createCallbackFcn(app, @Ki1EditFieldValueChanged, true);
            app.Kd1EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Kd1EditFieldLabel.Text = 'Kd1';
            app.Kd1EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Kd1EditField.ValueChangedFcn = createCallbackFcn(app, @Kd1EditFieldValueChanged, true);
            app.Kp2EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Kp2EditFieldLabel.Text = 'Kp2';
            app.Kp2EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Kp2EditField.ValueChangedFcn = createCallbackFcn(app, @Kp2EditFieldValueChanged, true);
            app.Ki2EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Ki2EditFieldLabel.Text = 'Ki2';
            app.Ki2EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Ki2EditField.ValueChangedFcn = createCallbackFcn(app, @Ki2EditFieldValueChanged, true);
            app.Kd2EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Kd2EditFieldLabel.Text = 'Kd2';
            app.Kd2EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Kd2EditField.ValueChangedFcn = createCallbackFcn(app, @Kd2EditFieldValueChanged, true);
            app.BrakeswitchSwitchLabel_2 = uilabel(app.PIDpositioncontrolTab);
            app.BrakeswitchSwitchLabel_2.Text = 'Brake switch';
            app.Brake1switchSwitch_2 = uiswitch(app.PIDpositioncontrolTab, 'slider');
            app.BrakeswitchSwitch_2Label_2 = uilabel(app.PIDpositioncontrolTab);
            app.BrakeswitchSwitch_2Label_2.Text = 'Brake switch';
            app.Brake2switchSwitch_2 = uiswitch(app.PIDpositioncontrolTab, 'slider');
            app.RunningswitchSwitchLabel = uilabel(app.PIDpositioncontrolTab);
            app.RunningswitchSwitchLabel.Text = {'Running'; 'switch'};
            app.RunningPIDswitchSwitch = uiswitch(app.PIDpositioncontrolTab, 'toggle');
            app.RunningPIDswitchSwitch.ValueChangedFcn = createCallbackFcn(app, @RunningPIDswitchSwitchValueChanged, true);
            app.RunningPIDswitchSwitch.Enable = 'off';
            app.Joint1EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Joint1EditField.ValueChangedFcn = createCallbackFcn(app, @Joint1EditFieldValueChanged, true);
            app.Joint2EditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.PIDpositioncontrolTab, 'numeric');
            app.Joint2EditField.ValueChangedFcn = createCallbackFcn(app, @Joint2EditFieldValueChanged, true);
            app.ImportedtrajectoryEditFieldLabel = uilabel(app.PIDpositioncontrolTab);
            app.ImportedtrajectoryEditFieldLabel.Text = 'Imported trajectory';
            app.ImportedtrajectoryEditField = uieditfield(app.PIDpositioncontrolTab, 'text');
            app.ImportedtrajectoryEditField.ValueChangingFcn = createCallbackFcn(app, @ImportedtrajectoryEditFieldValueChanging, true);
            app.ImportedtrajectoryEditField.Enable = 'off';
            app.TrajectoryDropDownLabel = uilabel(app.PIDpositioncontrolTab);
            app.TrajectoryDropDownLabel.Text = 'Trajectory';
            app.TrajectoryDropDown = uidropdown(app.PIDpositioncontrolTab);
            app.TrajectoryDropDown.Items = {'Default step functions', 'Costumed', 'Imported'};
            app.TrajectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @TrajectoryDropDownValueChanged, true);
            app.TrajectoryDropDown.Value = 'Default step functions';
            app.SimulateButton = uibutton(app.PIDpositioncontrolTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Text = 'Simulate';
            app.JointinitialpositionsLabel = uilabel(app.PIDpositioncontrolTab);
            app.JointinitialpositionsLabel.Text = 'Joint initial positions';
            app.DesiredtrajectoryLabel = uilabel(app.PIDpositioncontrolTab);
            app.DesiredtrajectoryLabel.Text = 'Desired trajectory';
            app.PIDConfirmButton = uibutton(app.PIDpositioncontrolTab, 'state');
            app.PIDConfirmButton.ValueChangedFcn = createCallbackFcn(app, @PIDConfirmButtonValueChanged, true);
            app.PIDConfirmButton.Enable = 'off';
            app.PIDConfirmButton.Text = 'Confirm';
            app.ExportButton = uibutton(app.PIDpositioncontrolTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.ControlgainsLabel = uilabel(app.PIDpositioncontrolTab);
            app.ControlgainsLabel.Text = 'Control gains';
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.RobotpositionSwitchLabel = uilabel(app.SettingsTab);
            app.RobotpositionSwitchLabel.Text = 'Robot position';
            app.RobotpositionSwitch = uiswitch(app.SettingsTab, 'slider');
            app.DecoggingSwitchLabel = uilabel(app.SettingsTab);
            app.DecoggingSwitchLabel.Text = 'Decogging';
            app.DecoggingSwitch = uiswitch(app.SettingsTab, 'slider');
            app.DecoggingSwitch.ValueChangedFcn = createCallbackFcn(app, @DecoggingSwitchValueChanged, true);
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.CommunicationLampLabel = uilabel(app.UIFigure);
            app.CommunicationLampLabel.Text = 'Communication';
            app.CommunicationLamp = uilamp(app.UIFigure);
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.CommandOptionsLabel = uilabel(app.UIFigure);
            app.CommandOptionsLabel.Text = 'Command Options';
            app.COMportEditFieldLabel = uilabel(app.UIFigure);
            app.COMportEditFieldLabel.Text = 'COM port';
            app.COMportEditField = uieditfield(app.UIFigure, 'text');
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.GUIforR2RRobotLabel = uilabel(app.UIFigure);
            app.GUIforR2RRobotLabel.Text = 'GUI for R2R Robot';
            app.SimulationPlot = uiaxes(app.UIFigure);
            title(app.SimulationPlot, 'Simulation')
            xlabel(app.SimulationPlot, 'X')
            ylabel(app.SimulationPlot, 'Y')
            app.SimulationPlot.XLim = [-2.2 2.2];
            app.SimulationPlot.YLim = [-2.2 2.2];
            app.SimulationPlot.ZLim = [-2.2 2.2];
            app.SimulationPlot.CLim = [0 1];
            app.SimulationPlot.XTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.SimulationPlot.YTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.SimulationPlot.ZTick = [0 0.5 1];
            app.CalibrationButton = uibutton(app.UIFigure, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Enable = 'off';
            app.CalibrationButton.Text = 'Calibration';
            app.PositionplotsLabel = uilabel(app.UIFigure);
            app.PositionplotsLabel.Text = 'Position plots';
            app.ErrorLampLabel = uilabel(app.UIFigure);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.UIFigure);
            app.SavingLampLabel = uilabel(app.UIFigure);
            app.SavingLampLabel.Text = 'Saving';
            app.SavingLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end