classdef R2R_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        R2RArmGUIUIFigure            matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PIDangleTab                  matlab.ui.container.Tab
        KpEditFieldLabel             matlab.ui.control.Label
        KpEditField                  matlab.ui.control.NumericEditField
        KdEditFieldLabel             matlab.ui.control.Label
        KdEditField                  matlab.ui.control.NumericEditField
        KiEditFieldLabel             matlab.ui.control.Label
        KiEditField                  matlab.ui.control.NumericEditField
        ThesevaluessetthedefaultPIDcontrolofthemotoroftheangleLabel  matlab.ui.control.Label
        LoadButton_2                 matlab.ui.control.Button
        ResetDefaultsButton          matlab.ui.control.Button
        PIDcurrentTab                matlab.ui.container.Tab
        KpEditField_2Label           matlab.ui.control.Label
        KpEditField_2                matlab.ui.control.NumericEditField
        KdEditField_2Label           matlab.ui.control.Label
        KdEditField_2                matlab.ui.control.NumericEditField
        KiEditField_2Label           matlab.ui.control.Label
        KiEditField_2                matlab.ui.control.NumericEditField
        ThesevaluessetthedefaultPIDcontrolofthemotorLabel_2  matlab.ui.control.Label
        LoadvaluesButton             matlab.ui.control.Button
        ResetDefaultsButton_2        matlab.ui.control.Button
        LoadArrayTab                 matlab.ui.container.Tab
        LoadCSVEditFieldLabel        matlab.ui.control.Label
        LoadCSVEditField             matlab.ui.control.EditField
        LoadButton                   matlab.ui.control.Button
        LoadWorkspaceArrayEditFieldLabel  matlab.ui.control.Label
        LoadWorkspaceArrayEditField  matlab.ui.control.EditField
        SettingsTab                  matlab.ui.container.Tab
        SavetoCSVCheckBox            matlab.ui.control.CheckBox
        SaveWorkspaceCheckBox        matlab.ui.control.CheckBox
        SimulationsafetyCheckBox     matlab.ui.control.CheckBox
        COMPORTEditFieldLabel        matlab.ui.control.Label
        COMPORTEditField             matlab.ui.control.EditField
        LiveDataCheckBox             matlab.ui.control.CheckBox
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        RunSimulationButton          matlab.ui.control.Button
        CalibrateSystemButton        matlab.ui.control.Button
        SaveDataButton               matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        AbortButton                  matlab.ui.control.Button
        R2RArmGUILabel               matlab.ui.control.Label
        SimulationcompeteLabel       matlab.ui.control.Label
        SimulationcompeteLamp        matlab.ui.control.Lamp
        ArrayloadedLampLabel         matlab.ui.control.Label
        ArrayloadedLamp              matlab.ui.control.Lamp
        SystemrunningLampLabel       matlab.ui.control.Label
        SystemrunningLamp            matlab.ui.control.Lamp
        DropDownLabel                matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        ListBoxLabel                 matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        ButtonGroup                  matlab.ui.container.ButtonGroup
        Button                       matlab.ui.control.RadioButton
        Button2                      matlab.ui.control.RadioButton
        Button3                      matlab.ui.control.RadioButton
        SliderLabel                  matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SpinnerLabel                 matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        Button4                      matlab.ui.control.StateButton
        ButtonGroup2                 matlab.ui.container.ButtonGroup
        Button_3                     matlab.ui.control.ToggleButton
        Button2_2                    matlab.ui.control.ToggleButton
        Button3_2                    matlab.ui.control.ToggleButton
        GaugeLabel                   matlab.ui.control.Label
        Gauge                        matlab.ui.control.Gauge
        KnobLabel                    matlab.ui.control.Label
        Knob                         matlab.ui.control.Knob
        Knob2Label                   matlab.ui.control.Label
        Knob2                        matlab.ui.control.DiscreteKnob
        SwitchLabel                  matlab.ui.control.Label
        Switch                       matlab.ui.control.ToggleSwitch
        Switch2Label                 matlab.ui.control.Label
        Switch2                      matlab.ui.control.RockerSwitch
        TextAreaLabel                matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
    end


    properties (Access = public)
    end

    properties (Access = private)
        serialHolder % This holds the serial object.
        serialOpen % Is serial Open?
        runProg % This is a flag that stops or starts the program on the chip
        currMatrix % This matrix holds the data that was collected
        CSVarray % Matrix 
        
        Property6 % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RunButton.Enable = 'off'; % disable on startup
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.SystemrunningLamp.Color = [0 1 0];
            app.serialHolder = serial(app.COMPORTEditField.value, 'BaudRate', 128000, 'DataBits', 8, 'StopBits', 1, 'Parity', 'none');
            %s.Terminator = 'CR'; 
            fopen(app.serialHolder);
            app.serialOpen = 1;
            app.runProg = 1;
            
            %Afloat = typecast(reply , 'single') % I added it
            
            lengthOfArray = 4000;
            
            testarray(4000) = 0;
            
            
            lastx = NaN
            app.currMatrix = 0 ;
            startSpot = 0;
            step = 1 ; % lowering step has a number of cycles and then acquire more data
            storedArray = zeros(lengthOfArray,1);
            % Send your variables here. 
            fwrite(app.serialHolder, 'b\n'); % Test by sending a char to the connected microcontroller
            
            
            for t = 1:lengthOfArray
                if (app.runProg)
                % For data receiving.
                %waitbar(t/lengthOfArray);
                %reply = fscanf(s)
                %reply = fread(s,4,'uchar')
                
                reply = fread(app.serialHolder, 1, 'float32'); %read 4 bytes and convert to float
                % For live plotting
                storedArray(t) = reply;
                app.currMatrix = [ app.currMatrix, reply ];
                plot(app.UIAxes3,app.currMatrix) ;
                if ((t/step)-500 < 0)
                    startSpot = 0;
                else
                    startSpot = (t/step)-500;
                end
                axis(app.UIAxes3,[startSpot, (t/step+50), 0 , 300 ]);
                grid(app.UIAxes3)
                drawnow limitrate;
                
                %pause(0.1); %wait for 0.1 sec but this is wher we do the computations
                end
            end
            app.RunButton.Enable = 'off';
            app.serialOpen = 0;
            fclose(app.serialHolder);
            
            app.SystemrunningLamp.Color = [1 0 0];
            lastx = 'r';
            
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            % fclose(app.serialHolder)
            app.runProg = 0;
            app.runButton.Enable = 'off';
            app.RunSimulationButton.Enable = 'off';
            app.SystemrunningLamp.Color = [1 0 0];
        end

        % Close request function: R2RArmGUIUIFigure
        function R2RArmGUIUIFigureCloseRequest(app, event)
            if (app.serialOpen)
                fclose(app.serialHolder);
            end
            delete(app)
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            mydate = datetime;
            savefile = strcat(num2str(mydate.Year),"-",num2str(mydate.Month),"-",num2str(mydate.Day),"-",num2str(mydate.Hour),"-",num2str(mydate.Minute),"-",num2str(mydate.Second,2),".csv")
            csvwrite(savefile,app.currMatrix'); %saves as CSV
            save(savefile); % Saves workspace
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.ArrayloadedLamp.Color = [0 1 0];
            app.RunSimulationButton.Enable = 'on';
            
            angleKP = app.KpEditField.Value;
            angleKD = app.KdEditField.Value;
            angleKI = app.KiEditField.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load Array
            app.CSVarray = csvread(app.LoadCSVEditField.Value);
            app.ArrayloadedLamp.Color = [0 1 0];
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            jointangles = [0: 0.01: 2*pi;0: 0.01: 2*pi]';
            %simple_arm_plot(app.UIAxes4,jointangles);
            app.SimulationcompeteLamp.Color = [0 1 0];
            app.RunButton.Enable = 'on';
        end

        % Size changed function: SettingsTab
        function SettingsTabSizeChanged(app, event)
          
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.R2RArmGUIUIFigure = uifigure;
            app.R2RArmGUIUIFigure.Name = 'R2R Arm GUI';
            app.R2RArmGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @R2RArmGUIUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.R2RArmGUIUIFigure);
            app.PIDangleTab = uitab(app.TabGroup);
            app.PIDangleTab.Title = 'PID, angle';
            app.KpEditFieldLabel = uilabel(app.PIDangleTab);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.PIDangleTab, 'numeric');
            app.KdEditFieldLabel = uilabel(app.PIDangleTab);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.PIDangleTab, 'numeric');
            app.KiEditFieldLabel = uilabel(app.PIDangleTab);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.PIDangleTab, 'numeric');
            app.ThesevaluessetthedefaultPIDcontrolofthemotoroftheangleLabel = uilabel(app.PIDangleTab);
            app.ThesevaluessetthedefaultPIDcontrolofthemotoroftheangleLabel.Text = {'These values set the default PID control of the motor '; 'of the angle. '};
            app.LoadButton_2 = uibutton(app.PIDangleTab, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.ResetDefaultsButton = uibutton(app.PIDangleTab, 'push');
            app.ResetDefaultsButton.Text = 'Reset Defaults';
            app.PIDcurrentTab = uitab(app.TabGroup);
            app.PIDcurrentTab.Title = 'PID, current';
            app.KpEditField_2Label = uilabel(app.PIDcurrentTab);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField_2 = uieditfield(app.PIDcurrentTab, 'numeric');
            app.KdEditField_2Label = uilabel(app.PIDcurrentTab);
            app.KdEditField_2Label.Text = 'Kd';
            app.KdEditField_2 = uieditfield(app.PIDcurrentTab, 'numeric');
            app.KiEditField_2Label = uilabel(app.PIDcurrentTab);
            app.KiEditField_2Label.Text = 'Ki';
            app.KiEditField_2 = uieditfield(app.PIDcurrentTab, 'numeric');
            app.ThesevaluessetthedefaultPIDcontrolofthemotorLabel_2 = uilabel(app.PIDcurrentTab);
            app.ThesevaluessetthedefaultPIDcontrolofthemotorLabel_2.Text = {'These values set the default PID of the current through '; 'the motor'};
            app.LoadvaluesButton = uibutton(app.PIDcurrentTab, 'push');
            app.LoadvaluesButton.Text = 'Load values';
            app.ResetDefaultsButton_2 = uibutton(app.PIDcurrentTab, 'push');
            app.ResetDefaultsButton_2.Text = 'Reset Defaults';
            app.LoadArrayTab = uitab(app.TabGroup);
            app.LoadArrayTab.Title = 'Load Array';
            app.LoadCSVEditFieldLabel = uilabel(app.LoadArrayTab);
            app.LoadCSVEditFieldLabel.Text = 'Load CSV';
            app.LoadCSVEditField = uieditfield(app.LoadArrayTab, 'text');
            app.LoadButton = uibutton(app.LoadArrayTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadWorkspaceArrayEditFieldLabel = uilabel(app.LoadArrayTab);
            app.LoadWorkspaceArrayEditFieldLabel.Text = 'Load Workspace Array';
            app.LoadWorkspaceArrayEditField = uieditfield(app.LoadArrayTab, 'text');
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.SizeChangedFcn = createCallbackFcn(app, @SettingsTabSizeChanged, true);
            app.SettingsTab.Title = 'Settings';
            app.SavetoCSVCheckBox = uicheckbox(app.SettingsTab);
            app.SavetoCSVCheckBox.Text = 'Save to CSV';
            app.SavetoCSVCheckBox.Value = true;
            app.SaveWorkspaceCheckBox = uicheckbox(app.SettingsTab);
            app.SaveWorkspaceCheckBox.Text = 'Save Workspace';
            app.SimulationsafetyCheckBox = uicheckbox(app.SettingsTab);
            app.SimulationsafetyCheckBox.Text = 'Simulation safety';
            app.SimulationsafetyCheckBox.Value = true;
            app.COMPORTEditFieldLabel = uilabel(app.SettingsTab);
            app.COMPORTEditFieldLabel.Text = 'COM PORT';
            app.COMPORTEditField = uieditfield(app.SettingsTab, 'text');
            app.COMPORTEditField.Value = 'COM10';
            app.LiveDataCheckBox = uicheckbox(app.SettingsTab);
            app.LiveDataCheckBox.Text = 'Live Data';
            app.LiveDataCheckBox.Value = true;
            app.UIAxes3 = uiaxes(app.R2RArmGUIUIFigure);
            title(app.UIAxes3, 'Data')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.R2RArmGUIUIFigure);
            title(app.UIAxes4, 'Simulation')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.RunSimulationButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.CalibrateSystemButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.CalibrateSystemButton.Text = 'Calibrate System';
            app.SaveDataButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.RunButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AbortButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.R2RArmGUILabel = uilabel(app.R2RArmGUIUIFigure);
            app.R2RArmGUILabel.Text = 'R2R Arm GUI';
            app.SimulationcompeteLabel = uilabel(app.R2RArmGUIUIFigure);
            app.SimulationcompeteLabel.Text = {'Simulation'; ' compete'};
            app.SimulationcompeteLamp = uilamp(app.R2RArmGUIUIFigure);
            app.ArrayloadedLampLabel = uilabel(app.R2RArmGUIUIFigure);
            app.ArrayloadedLampLabel.Text = {'Array'; 'loaded'};
            app.ArrayloadedLamp = uilamp(app.R2RArmGUIUIFigure);
            app.SystemrunningLampLabel = uilabel(app.R2RArmGUIUIFigure);
            app.SystemrunningLampLabel.Text = {'System'; 'running'};
            app.SystemrunningLamp = uilamp(app.R2RArmGUIUIFigure);
            app.DropDownLabel = uilabel(app.R2RArmGUIUIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.R2RArmGUIUIFigure);
            app.ListBoxLabel = uilabel(app.R2RArmGUIUIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.R2RArmGUIUIFigure);
            app.ListBox.Value = 'Item 2';
            app.ButtonGroup = uibuttongroup(app.R2RArmGUIUIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.SliderLabel = uilabel(app.R2RArmGUIUIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.R2RArmGUIUIFigure);
            app.SpinnerLabel = uilabel(app.R2RArmGUIUIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.R2RArmGUIUIFigure);
            app.Button4 = uibutton(app.R2RArmGUIUIFigure, 'state');
            app.Button4.Text = 'Button4';
            app.ButtonGroup2 = uibuttongroup(app.R2RArmGUIUIFigure);
            app.ButtonGroup2.Title = 'Button Group2';
            app.Button_3 = uitogglebutton(app.ButtonGroup2);
            app.Button_3.Text = 'Button';
            app.Button_3.Value = true;
            app.Button2_2 = uitogglebutton(app.ButtonGroup2);
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uitogglebutton(app.ButtonGroup2);
            app.Button3_2.Text = 'Button3';
            app.GaugeLabel = uilabel(app.R2RArmGUIUIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.R2RArmGUIUIFigure, 'circular');
            app.KnobLabel = uilabel(app.R2RArmGUIUIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.R2RArmGUIUIFigure, 'continuous');
            app.Knob2Label = uilabel(app.R2RArmGUIUIFigure);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.R2RArmGUIUIFigure, 'discrete');
            app.SwitchLabel = uilabel(app.R2RArmGUIUIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.R2RArmGUIUIFigure, 'toggle');
            app.Switch2Label = uilabel(app.R2RArmGUIUIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.R2RArmGUIUIFigure, 'rocker');
            app.TextAreaLabel = uilabel(app.R2RArmGUIUIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.R2RArmGUIUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = R2R_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.R2RArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.R2RArmGUIUIFigure)
        end
    end
end