classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        R2RArmGUIUIFigure       matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        PIDangleTab             matlab.ui.container.Tab
        KpEditFieldLabel        matlab.ui.control.Label
        KpEditField             matlab.ui.control.NumericEditField
        KdEditFieldLabel        matlab.ui.control.Label
        KdEditField             matlab.ui.control.NumericEditField
        KiEditFieldLabel        matlab.ui.control.Label
        KiEditField             matlab.ui.control.NumericEditField
        ThesevaluessetthedefaultPIDcontrolofthemotoroftheangleLabel  matlab.ui.control.Label
        LoadButton_2            matlab.ui.control.Button
        ResetDefaultsButton     matlab.ui.control.Button
        PIDcurrentTab           matlab.ui.container.Tab
        KpEditField_2Label      matlab.ui.control.Label
        KpEditField_2           matlab.ui.control.NumericEditField
        KdEditField_2Label      matlab.ui.control.Label
        KdEditField_2           matlab.ui.control.NumericEditField
        KiEditField_2Label      matlab.ui.control.Label
        KiEditField_2           matlab.ui.control.NumericEditField
        ThesevaluessetthedefaultPIDcontrolofthemotorLabel_2  matlab.ui.control.Label
        LoadvaluesButton        matlab.ui.control.Button
        ResetDefaultsButton_2   matlab.ui.control.Button
        LoadArrayTab            matlab.ui.container.Tab
        LoadCSVEditFieldLabel   matlab.ui.control.Label
        LoadCSVEditField        matlab.ui.control.EditField
        LoadButton              matlab.ui.control.Button
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        RunSimulationButton     matlab.ui.control.Button
        CalibrateSystemButton   matlab.ui.control.Button
        SaveDataButton          matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        AbortButton             matlab.ui.control.Button
        R2RArmGUILabel          matlab.ui.control.Label
        SimulationcompeteLabel  matlab.ui.control.Label
        SimulationcompeteLamp   matlab.ui.control.Lamp
        ArrayloadedLampLabel    matlab.ui.control.Label
        ArrayloadedLamp         matlab.ui.control.Lamp
        SystemrunningLampLabel  matlab.ui.control.Label
        SystemrunningLamp       matlab.ui.control.Lamp
        LiveButton              matlab.ui.control.StateButton
    end


    properties (Access = public)
    end

    properties (Access = private)
        serialHolder % This holds the serial object.
        runProg
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.SystemrunningLamp.Color = [0 1 0];
            app.serialHolder = serial('COM10', 'BaudRate', 128000, 'DataBits', 8, 'StopBits', 1, 'Parity', 'none');
            %s.Terminator = 'CR'; 
            fopen(app.serialHolder);
            app.runProg = 1;
            
            %Afloat = typecast(reply , 'single') % I added it
            command = 1;
            lengthOfArray = 4000;
            
            testarray(4000) = 0;
            x = NaN;
            level = 1;
            lastx = NaN
            x = 0 ;
            startSpot = 0;
            step = 1 ; % lowering step has a number of cycles and then acquire more data
            storedArray = zeros(lengthOfArray,1);
            % Send your variables here. 
            fwrite(app.serialHolder, 'b\n'); % Test by sending a char to the connected microcontroller
            
            
            for t = 1:lengthOfArray
                if (app.runProg)
                % For data receiving.
                %waitbar(t/lengthOfArray);
                %reply = fscanf(s)
                %reply = fread(s,4,'uchar')
                
                reply = fread(app.serialHolder, 1, 'float32'); %read 4 bytes and convert to float
                % For live plotting
                storedArray(t) = reply;
                x = [ x, reply ];
                plot(app.UIAxes3,x) ;
                if ((t/step)-500 < 0)
                    startSpot = 0;
                else
                    startSpot = (t/step)-500;
                end
                axis(app.UIAxes3,[startSpot, (t/step+50), 0 , 300 ]);
                grid(app.UIAxes3)
                drawnow limitrate;
                
                %pause(0.1); %wait for 0.1 sec but this is wher we do the computations
                end
            end
           
            fclose(app.serialHolder)
            app.SystemrunningLamp.Color = [1 0 0];
            lastx = 'r';
            x = NaN;
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            % fclose(app.serialHolder)
            app.runProg = 0;
            app.SystemrunningLamp.Color = [1 0 0];
        end

        % Close request function: R2RArmGUIUIFigure
        function R2RArmGUIUIFigureCloseRequest(app, event)
            fclose(app.serialHolder);
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.R2RArmGUIUIFigure = uifigure;
            app.R2RArmGUIUIFigure.Name = 'R2R Arm GUI';
            app.R2RArmGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @R2RArmGUIUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.R2RArmGUIUIFigure);
            app.PIDangleTab = uitab(app.TabGroup);
            app.PIDangleTab.Title = 'PID, angle';
            app.KpEditFieldLabel = uilabel(app.PIDangleTab);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.PIDangleTab, 'numeric');
            app.KdEditFieldLabel = uilabel(app.PIDangleTab);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.PIDangleTab, 'numeric');
            app.KiEditFieldLabel = uilabel(app.PIDangleTab);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.PIDangleTab, 'numeric');
            app.ThesevaluessetthedefaultPIDcontrolofthemotoroftheangleLabel = uilabel(app.PIDangleTab);
            app.ThesevaluessetthedefaultPIDcontrolofthemotoroftheangleLabel.Text = {'These values set the default PID control of the motor '; 'of the angle. '};
            app.LoadButton_2 = uibutton(app.PIDangleTab, 'push');
            app.LoadButton_2.Text = 'Load';
            app.ResetDefaultsButton = uibutton(app.PIDangleTab, 'push');
            app.ResetDefaultsButton.Text = 'Reset Defaults';
            app.PIDcurrentTab = uitab(app.TabGroup);
            app.PIDcurrentTab.Title = 'PID, current';
            app.KpEditField_2Label = uilabel(app.PIDcurrentTab);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField_2 = uieditfield(app.PIDcurrentTab, 'numeric');
            app.KdEditField_2Label = uilabel(app.PIDcurrentTab);
            app.KdEditField_2Label.Text = 'Kd';
            app.KdEditField_2 = uieditfield(app.PIDcurrentTab, 'numeric');
            app.KiEditField_2Label = uilabel(app.PIDcurrentTab);
            app.KiEditField_2Label.Text = 'Ki';
            app.KiEditField_2 = uieditfield(app.PIDcurrentTab, 'numeric');
            app.ThesevaluessetthedefaultPIDcontrolofthemotorLabel_2 = uilabel(app.PIDcurrentTab);
            app.ThesevaluessetthedefaultPIDcontrolofthemotorLabel_2.Text = {'These values set the default PID of the current through '; 'the motor'};
            app.LoadvaluesButton = uibutton(app.PIDcurrentTab, 'push');
            app.LoadvaluesButton.Text = 'Load values';
            app.ResetDefaultsButton_2 = uibutton(app.PIDcurrentTab, 'push');
            app.ResetDefaultsButton_2.Text = 'Reset Defaults';
            app.LoadArrayTab = uitab(app.TabGroup);
            app.LoadArrayTab.Title = 'Load Array';
            app.LoadCSVEditFieldLabel = uilabel(app.LoadArrayTab);
            app.LoadCSVEditFieldLabel.Text = 'Load CSV';
            app.LoadCSVEditField = uieditfield(app.LoadArrayTab, 'text');
            app.LoadButton = uibutton(app.LoadArrayTab, 'push');
            app.LoadButton.Text = 'Load';
            app.UIAxes3 = uiaxes(app.R2RArmGUIUIFigure);
            title(app.UIAxes3, 'Data')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.R2RArmGUIUIFigure);
            title(app.UIAxes4, 'Simulation')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.RunSimulationButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.RunSimulationButton.Text = 'Run Simulation';
            app.CalibrateSystemButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.CalibrateSystemButton.Text = 'Calibrate System';
            app.SaveDataButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.RunButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AbortButton = uibutton(app.R2RArmGUIUIFigure, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.R2RArmGUILabel = uilabel(app.R2RArmGUIUIFigure);
            app.R2RArmGUILabel.Text = 'R2R Arm GUI';
            app.SimulationcompeteLabel = uilabel(app.R2RArmGUIUIFigure);
            app.SimulationcompeteLabel.Text = {'Simulation'; ' compete'};
            app.SimulationcompeteLamp = uilamp(app.R2RArmGUIUIFigure);
            app.ArrayloadedLampLabel = uilabel(app.R2RArmGUIUIFigure);
            app.ArrayloadedLampLabel.Text = {'Array'; 'loaded'};
            app.ArrayloadedLamp = uilamp(app.R2RArmGUIUIFigure);
            app.SystemrunningLampLabel = uilabel(app.R2RArmGUIUIFigure);
            app.SystemrunningLampLabel.Text = {'System'; 'running'};
            app.SystemrunningLamp = uilamp(app.R2RArmGUIUIFigure);
            app.LiveButton = uibutton(app.R2RArmGUIUIFigure, 'state');
            app.LiveButton.Text = 'Live';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.R2RArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.R2RArmGUIUIFigure)
        end
    end
end